classdef mainWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TechnologySelectionPanel     matlab.ui.container.Panel
        TechnologyNodeDropDownLabel  matlab.ui.control.Label
        TechnologyNodeDropDown       matlab.ui.control.DropDown
        CircuitSelectionPanel        matlab.ui.container.Panel
        CircuitDropDownLabel         matlab.ui.control.Label
        CircuitDropDown              matlab.ui.control.DropDown
        SimulateButton               matlab.ui.control.Button
        OutputSettingsPanel          matlab.ui.container.Panel
        AverageCouplingLengthPanel   matlab.ui.container.Panel
        DC1CheckBox                  matlab.ui.control.CheckBox
        DC2CheckBox                  matlab.ui.control.CheckBox
        regSCheckBox                 matlab.ui.control.CheckBox
        regCCheckBox                 matlab.ui.control.CheckBox
        PlotButtonGroup              matlab.ui.container.ButtonGroup
        AllButton                    matlab.ui.control.RadioButton
        TopButton                    matlab.ui.control.RadioButton
        NoneButton                   matlab.ui.control.RadioButton
        DispersionCrurvesPanel       matlab.ui.container.Panel
        DC1CheckBox_2                matlab.ui.control.CheckBox
        DC2CheckBox_2                matlab.ui.control.CheckBox
        regSCheckBox_2               matlab.ui.control.CheckBox
        regCCheckBox_2               matlab.ui.control.CheckBox
        DesignSettingsPanel          matlab.ui.container.Panel
        regSCheckBox_3               matlab.ui.control.CheckBox
        regCCheckBox_3               matlab.ui.control.CheckBox
        InputsPanel                  matlab.ui.container.Panel
        BEditFieldLabel              matlab.ui.control.Label
        BEditField                   matlab.ui.control.EditField
        AEditFieldLabel              matlab.ui.control.Label
        AEditField                   matlab.ui.control.EditField
        CEditFieldLabel              matlab.ui.control.Label
        CEditField                   matlab.ui.control.EditField
        inputsInstructions           matlab.ui.control.Label
        DEditFieldLabel              matlab.ui.control.Label
        DEditField                   matlab.ui.control.EditField
        AddersimulationsPanel        matlab.ui.container.Panel
        NbitSpinnerLabel             matlab.ui.control.Label
        NbitSpinner                  matlab.ui.control.Spinner
        RCARandominputsCheckBox      matlab.ui.control.CheckBox
        CSARandominputsCheckBox      matlab.ui.control.CheckBox
        NsimSpinnerLabel             matlab.ui.control.Label
        NsimSpinner                  matlab.ui.control.Spinner
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            % add simulation path with correct separator
            %addpath(genpath(['simulation' filesep]))
            restoredefaultpath
            
            baseFolder = pwd;
            addingFolders = dir([baseFolder filesep '..' filesep])
        
            for folderID = 3:size(addingFolders, 1)
           
                if(addingFolders(folderID).isdir)
                    addingPath = [baseFolder filesep '..' filesep addingFolders(folderID).name]
                    addpath(genpath([baseFolder filesep '..' filesep addingFolders(folderID).name]));
                end
            end

            model = app.TechnologyNodeDropDown.Value;
            plot_info = "plot_top";
            if app.AllButton.Value
                plot_info = "plot_all";
            elseif app.NoneButton.Value
                plot_info = "no_plot";
            end
            opt_parameters = {};
            for element = app.AverageCouplingLengthPanel.Children'
                if element.Value
                    opt_parameters{end+1} = element.Text+"_Lc_avg";
                end
            end
            
            for element = app.DispersionCrurvesPanel.Children'
                if element.Value
                    opt_parameters{end+1} = element.Text+"_dispersion_curves";
                end
            end
            
            for element = app.DesignSettingsPanel.Children'
                if ~element.Value
                    opt_parameters{end+1} = "DC_without_"+element.Text;
                end
            end
            
            
            if app.RCARandominputsCheckBox.Value
                Nbit = app.NbitSpinner.Value;
                N_simulation = app.NsimSpinner.Value;
                adder=1;
                
                Adders_simulator
                return
            end
            
            if app.CSARandominputsCheckBox.Value
                Nbit = app.NbitSpinner.Value;
                N_simulation = app.NsimSpinner.Value;
                adder=2;
                
                Adders_simulator
                return
            end
            
            
            A_in = app.AEditField.Value;
            B_in = app.BEditField.Value;
            C_in = app.CEditField.Value;
            D_in = app.DEditField.Value;
            if isempty(A_in) || isempty(B_in) || isempty(C_in) || isempty(D_in)
                return
            end
            A_bin = str2num(A_in);
            B_bin = str2num(B_in);
            C_bin = str2num(C_in);
            D_bin = str2num(D_in);
            
            circuit = app.CircuitDropDown.Value;

            Generic_simulator
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TechnologySelectionPanel = uipanel(app.UIFigure);
            app.TechnologySelectionPanel.Title = 'Technology Selection';
            app.TechnologyNodeDropDownLabel = uilabel(app.TechnologySelectionPanel);
            app.TechnologyNodeDropDownLabel.Text = 'Technology Node';
            app.TechnologyNodeDropDown = uidropdown(app.TechnologySelectionPanel);
            app.TechnologyNodeDropDown.Items = {'YIG 100nm', 'YIG 30nm'};
            app.TechnologyNodeDropDown.Value = 'YIG 100nm';
            app.CircuitSelectionPanel = uipanel(app.UIFigure);
            app.CircuitSelectionPanel.Title = 'Circuit Selection';
            app.CircuitDropDownLabel = uilabel(app.CircuitSelectionPanel);
            app.CircuitDropDownLabel.Text = 'Circuit';
            app.CircuitDropDown = uidropdown(app.CircuitSelectionPanel);
            app.CircuitDropDown.Items = {'AND(A,B)', 'AND4(A,B,C,D)', 'N-bit Carry_skip_adder(A,B,C=Carry_in)', 'FA(A,B,C)', 'HA(A,B)', 'Mux2to1(A,B,C=sel)', 'NOT(A)', 'OR(A,B)', 'N-bit RCA(A,B,C=Carry_in)', 'waveguide(A,Lw)', 'XOR(A,B)'};
            app.CircuitDropDown.Value = 'AND(A,B)';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.OutputSettingsPanel = uipanel(app.UIFigure);
            app.OutputSettingsPanel.Title = 'Output Settings';
            app.AverageCouplingLengthPanel = uipanel(app.OutputSettingsPanel);
            app.AverageCouplingLengthPanel.Title = 'Average Coupling Length';
            app.DC1CheckBox = uicheckbox(app.AverageCouplingLengthPanel);
            app.DC1CheckBox.Text = 'DC1';
            app.DC2CheckBox = uicheckbox(app.AverageCouplingLengthPanel);
            app.DC2CheckBox.Text = 'DC2';
            app.regSCheckBox = uicheckbox(app.AverageCouplingLengthPanel);
            app.regSCheckBox.Text = 'regS';
            app.regCCheckBox = uicheckbox(app.AverageCouplingLengthPanel);
            app.regCCheckBox.Text = 'regC';
            app.PlotButtonGroup = uibuttongroup(app.OutputSettingsPanel);
            app.PlotButtonGroup.Title = 'Plot';
            app.AllButton = uiradiobutton(app.PlotButtonGroup);
            app.AllButton.Text = 'All';
            app.TopButton = uiradiobutton(app.PlotButtonGroup);
            app.TopButton.Text = 'Top';
            app.TopButton.Value = true;
            app.NoneButton = uiradiobutton(app.PlotButtonGroup);
            app.NoneButton.Text = 'None';
            app.DispersionCrurvesPanel = uipanel(app.UIFigure);
            app.DispersionCrurvesPanel.Title = 'Dispersion Crurves';
            app.DC1CheckBox_2 = uicheckbox(app.DispersionCrurvesPanel);
            app.DC1CheckBox_2.Text = 'DC1';
            app.DC2CheckBox_2 = uicheckbox(app.DispersionCrurvesPanel);
            app.DC2CheckBox_2.Text = 'DC2';
            app.regSCheckBox_2 = uicheckbox(app.DispersionCrurvesPanel);
            app.regSCheckBox_2.Text = 'regS';
            app.regCCheckBox_2 = uicheckbox(app.DispersionCrurvesPanel);
            app.regCCheckBox_2.Text = 'regC';
            app.DesignSettingsPanel = uipanel(app.UIFigure);
            app.DesignSettingsPanel.Title = 'Design Settings';
            app.regSCheckBox_3 = uicheckbox(app.DesignSettingsPanel);
            app.regSCheckBox_3.Text = 'regS';
            app.regSCheckBox_3.Value = true;
            app.regCCheckBox_3 = uicheckbox(app.DesignSettingsPanel);
            app.regCCheckBox_3.Text = 'regC';
            app.regCCheckBox_3.Value = true;
            app.InputsPanel = uipanel(app.UIFigure);
            app.InputsPanel.Title = 'Inputs';
            app.BEditFieldLabel = uilabel(app.InputsPanel);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.InputsPanel, 'text');
            app.BEditField.Value = '[1;0;1]';
            app.AEditFieldLabel = uilabel(app.InputsPanel);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.InputsPanel, 'text');
            app.AEditField.Value = '[0;1;1]';
            app.CEditFieldLabel = uilabel(app.InputsPanel);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.InputsPanel, 'text');
            app.CEditField.Value = '[0;0;0]';
            app.inputsInstructions = uilabel(app.InputsPanel);
            app.inputsInstructions.Text = {'Insert input values in binary format as '; 'column vectors. Each posisition in the '; 'vectors will be a single simulation. '; 'In case of N-bit inputs insert a N-bit '; 'columns matrix where each line will be a '; 'single simulation.'};
            app.DEditFieldLabel = uilabel(app.InputsPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.InputsPanel, 'text');
            app.DEditField.Value = '[0;0;0]';
            app.AddersimulationsPanel = uipanel(app.UIFigure);
            app.AddersimulationsPanel.Title = 'Adder simulations';
            app.NbitSpinnerLabel = uilabel(app.AddersimulationsPanel);
            app.NbitSpinnerLabel.Text = 'Nbit';
            app.NbitSpinner = uispinner(app.AddersimulationsPanel);
            app.NbitSpinner.Value = 16;
            app.RCARandominputsCheckBox = uicheckbox(app.AddersimulationsPanel);
            app.RCARandominputsCheckBox.Text = 'RCA Random inputs';
            app.CSARandominputsCheckBox = uicheckbox(app.AddersimulationsPanel);
            app.CSARandominputsCheckBox.Text = 'CSA Random inputs';
            app.NsimSpinnerLabel = uilabel(app.AddersimulationsPanel);
            app.NsimSpinnerLabel.Text = 'Nsim';
            app.NsimSpinner = uispinner(app.AddersimulationsPanel);
            app.NsimSpinner.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end